classdef SampleNicla < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SampleArduinoNiclaUIFigure  matlab.ui.Figure
        SampleArduinoNiclaLabel     matlab.ui.control.Label
        BaudrateDropDown            matlab.ui.control.DropDown
        BaudrateDropDownLabel       matlab.ui.control.Label
        SerialPortDropDown          matlab.ui.control.DropDown
        SerialPortDropDownLabel     matlab.ui.control.Label
        StartRecordingButton        matlab.ui.control.Button
    end

   
    properties (Access = private)
        keepSampling = false; % Description
        samplingProcess; % Description
        rawData = [];
        serialHandle;
    end
    
    methods (Access = private)
        
        function startSampleData(app)
            app.serialHandle = serialport(app.SerialPortDropDown.Value, str2double(app.BaudrateDropDown.Value));
            app.serialHandle.Timeout = 1; % timeout in seconds
            app.rawData = [];            
            app.serialHandle.flush();
            %app.serialHandle.BytesAvailableFcn = {};
            %app.serialHandle.BytesAvailableFcnMode = "terminator";
            configureCallback(app.serialHandle,"terminator",@app.bytesAvailable)
        end

        function bytesAvailable(app, src, event) 
            incomming = app.serialHandle.readline();
            disp(incomming)
            if(isempty(incomming))
                app.keepSampling = false;
            else 
                incomingNum = split(incomming, ',',2);
                app.rawData(end+1,:) = str2double(incomingNum);
            end
            
        end

        function stopSampling(app) 
            app.serialHandle = '';
            
            if(size(app.rawData) > 0)
                rawDataArduino = app.rawData;
                save(datestr(now, 'dd-mm-yyyy@HH-MM-SS'), "rawDataArduino");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            if app.keepSampling == false
                app.keepSampling = true;
                app.startSampleData();
                app.StartRecordingButton.Text = "Stop Recroding";
            else
                app.keepSampling = false;
                app.StartRecordingButton.Text = "Start Recroding";
                app.stopSampling();
            end
        end

        % Drop down opening function: SerialPortDropDown
        function SerialPortDropDownOpening(app, event)
            app.SerialPortDropDown.Items = serialportlist;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SampleArduinoNiclaUIFigure = uifigure('Visible', 'off');
            app.SampleArduinoNiclaUIFigure.Name = 'Sample Arduino Nicla';
            app.StartRecordingButton = uibutton(app.SampleArduinoNiclaUIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.SerialPortDropDownLabel = uilabel(app.SampleArduinoNiclaUIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.SampleArduinoNiclaUIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortDropDownOpening, true);
            app.SerialPortDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.SampleArduinoNiclaUIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.SampleArduinoNiclaUIFigure);
            app.BaudrateDropDown.Items = {'9600', '115200'};
            app.BaudrateDropDown.Value = '115200';
            app.SampleArduinoNiclaLabel = uilabel(app.SampleArduinoNiclaUIFigure);
            app.SampleArduinoNiclaLabel.Text = 'Sample Arduino Nicla';
            % Show the figure after all components are created
            app.SampleArduinoNiclaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampleNicla

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SampleArduinoNiclaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SampleArduinoNiclaUIFigure)
        end
    end
end